classdef DeviceContrl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        VButton_2                   matlab.ui.control.Button
        VButton                     matlab.ui.control.Button
        Lamp_6                      matlab.ui.control.Lamp
        Lamp_5                      matlab.ui.control.Lamp
        HzLabel                     matlab.ui.control.Label
        microsecondsLabel           matlab.ui.control.Label
        VoltsLabel                  matlab.ui.control.Label
        PreviewStatusLamp           matlab.ui.control.Lamp
        PreviewStatusLampLabel      matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        FileNameEditField           matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
        SaveLabel                   matlab.ui.control.Label
        pStopButton                 matlab.ui.control.Button
        pStartButton                matlab.ui.control.Button
        PreviewLabel                matlab.ui.control.Label
        CaptureStatusLamp           matlab.ui.control.Lamp
        CaptureStatusLampLabel      matlab.ui.control.Label
        SplitPolzImgButton          matlab.ui.control.Button
        RawImgButton                matlab.ui.control.Button
        EditField2                  matlab.ui.control.NumericEditField
        LEDVotageKnob               matlab.ui.control.Knob
        LEDVotageKnobLabel          matlab.ui.control.Label
        FrameRateEditField          matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel     matlab.ui.control.Label
        StartCaptureButton          matlab.ui.control.Button
        ExposureTimeEditField       matlab.ui.control.NumericEditField
        ExposureTimeEditFieldLabel  matlab.ui.control.Label
        CameraPropertiesLabel       matlab.ui.control.Label
        LucidStartButton_2          matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        ArudinoStartButton          matlab.ui.control.Button
        InitializeInstrumentsLabel  matlab.ui.control.Label
        OffButton                   matlab.ui.control.Button
        AllButton                   matlab.ui.control.Button
        LEDControlLabel             matlab.ui.control.Label
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        Lamp_4Label                 matlab.ui.control.Label
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_3Label                 matlab.ui.control.Label
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_2Label                 matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        Lamp                        matlab.ui.control.Lamp
        LampLabel                   matlab.ui.control.Label
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        a;  % arduino initialization
        f;  % raw images
        pf; % Split images
        fg1; % Flag 1
        fg2; % Flag 2
        lv;  %LED knob voltage
        v;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 45
            app.fg2=0;
            while app.fg2==0
                app.lv = app.LEDVotageKnob.Value/5;
                app.EditField2.Value=app.lv*5;
                writePWMDutyCycle(app.a,'D5',0);
                writePWMDutyCycle(app.a,'D9',0);
                writePWMDutyCycle(app.a,'D11',0);
                writePWMDutyCycle(app.a,'D3',app.lv);   %45
                app.Lamp.Color=([0 app.lv 0]);
                app.Lamp_2.Color=([0 0 0]);
                app.Lamp_3.Color=([0 0 0]);
                app.Lamp_4.Color=([0 0 0]);
            end

        end

        % Button pushed function: ArudinoStartButton
        function ArudinoStartButtonPushed(app, event)
            % init arduino
            app.Lamp_5.Color = [1 1 0];
            app.a = arduino();
            app.lv = app.LEDVotageKnob.Value/5;
            app.EditField2.Value=app.lv*5;
            app.Lamp_5.Color = [0 1 0];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 0
            app.fg2=0;
            while app.fg2==0
                app.lv = app.LEDVotageKnob.Value/5;
                app.EditField2.Value=app.lv*5;
                writePWMDutyCycle(app.a,'D3',0);
                writePWMDutyCycle(app.a,'D9',0);
                writePWMDutyCycle(app.a,'D11',0);
                writePWMDutyCycle(app.a,'D5',1);   %0
                app.Lamp_2.Color=([0 app.lv 0]);
                app.Lamp.Color  =([0 0 0]);
                app.Lamp_3.Color=([0 0 0]);
                app.Lamp_4.Color=([0 0 0]);
            end
        end

        % Button pushed function: LucidStartButton_2
        function LucidStartButton_2Pushed(app, event)
            %init camera
            app.Lamp_6.Color = [1 1 0];
            pause(1)
            app.v = gigecam('169.254.144.173');
            app.v.PixelFormat = 'Mono12';
            app.v.ExposureAuto = 'Off';
            app.v.AcquisitionFrameRateEnable = 'True';
            app.Lamp_6.Color = [0 1 0];

        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            % all off
            app.LEDVotageKnob.Value = 0;
            app.lv = app.LEDVotageKnob.Value;
            app.EditField2.Value=app.lv*5;
            writePWMDutyCycle(app.a,'D5',0);
            writePWMDutyCycle(app.a,'D9',0);
            writePWMDutyCycle(app.a,'D11',0);
            writePWMDutyCycle(app.a,'D3',0);   %45
            app.Lamp.Color=([0 0 0]);
            app.Lamp_2.Color=([0 0 0]);
            app.Lamp_3.Color=([0 0 0]);
            app.Lamp_4.Color=([0 0 0]);

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 90
            app.fg2=0;
            while app.fg2==0
                app.lv = app.LEDVotageKnob.Value/5;
                app.EditField2.Value=app.lv*5;
                writePWMDutyCycle(app.a,'D3',0);
                writePWMDutyCycle(app.a,'D5',0);
                writePWMDutyCycle(app.a,'D11',0);
                writePWMDutyCycle(app.a,'D9',app.lv);   %45
                app.Lamp_3.Color=([0 app.lv 0]);
                app.Lamp_2.Color=([0 0 0]);
                app.Lamp.Color=([0 0 0]);
                app.Lamp_4.Color=([0 0 0]);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % Circular
            app.fg2=0;
            while app.fg2==0
                app.lv = app.LEDVotageKnob.Value/5;
                app.EditField2.Value=app.lv*5;
                writePWMDutyCycle(app.a,'D3',0);
                writePWMDutyCycle(app.a,'D5',0);
                writePWMDutyCycle(app.a,'D9',0);
                writePWMDutyCycle(app.a,'D11',app.lv);   %45
                app.Lamp_4.Color=([0 app.lv 0]);
                app.Lamp.Color=([0 0 0]);
                app.Lamp_2.Color=([0 0 0]);
                app.Lamp_3.Color=([0 0 0]);
            end
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            % all LED
            app.fg2=1;
            app.lv = app.LEDVotageKnob.Value/5;
            app.EditField2.Value=app.lv*5;
            writePWMDutyCycle(app.a,'D3',app.lv);
            writePWMDutyCycle(app.a,'D5',app.lv);
            writePWMDutyCycle(app.a,'D9',app.lv);
            writePWMDutyCycle(app.a,'D11',app.lv);
            app.Lamp_4.Color=([0 app.lv 0]);
            app.Lamp.Color=([0 app.lv 0]);
            app.Lamp_2.Color=([0 app.lv 0]);
            app.Lamp_3.Color=([0 app.lv 0]);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            app.LEDVotageKnob.Value = 0;
            app.EditField2.Value = 0;
            app.fg2=1;
            app.fg1=1;
            cla(app.UIAxes_5,app.UIAxes_4)
            clear app.a app.v app.f app.pf;
            app.Lamp_5.Color=([0 0 0]);
            app.Lamp_6.Color=([0 0 0]);
        end

        % Button pushed function: StartCaptureButton
        function StartCaptureButtonPushed(app, event)
            % start image capture
            app.fg2=1;
            app.CaptureStatusLamp.Color = ([1 0 0]);

            if app.FrameRateEditField.Value == 0 && app.ExposureTimeEditField.Value==0
                app.FrameRateEditField.Value=5;
                app.ExposureTimeEditField.Value=100000;
            else
            end
            app.v.AcquisitionFrameRate = app.FrameRateEditField.Value;
            app.v.ExposureTime = app.ExposureTimeEditField.Value; %microseconds

            app.lv = app.LEDVotageKnob.Value/5;
            app.EditField2.Value=app.lv*5;
            ledv = app.lv;

            writePWMDutyCycle(app.a,'D3',ledv);
            pause(1)
            app.f(:,:,1)=snapshot(app.v);
            pause(1)
            writePWMDutyCycle(app.a,'D3',0);
            writePWMDutyCycle(app.a,'D5',ledv);
            pause(1)
            app.f(:,:,2)=snapshot(app.v);
            pause(1)
            writePWMDutyCycle(app.a,'D5',0);
            writePWMDutyCycle(app.a,'D9',ledv);
            pause(1)
            app.f(:,:,3)=snapshot(app.v);
            pause(1)
            writePWMDutyCycle(app.a,'D9',0);
            writePWMDutyCycle(app.a,'D11',ledv);
            pause(1)
            app.f(:,:,4)=snapshot(app.v);
            pause(1)
            writePWMDutyCycle(app.a,'D11',0);

            % 135 degrees
            app.pf(:,:,1) = app.f(2:2:2048, 1:2:2448, 1);
            % 0 degrees
            app.pf(:,:,2) = app.f(2:2:2048, 2:2:2448, 2);
            % 90 degrees
            app.pf(:,:,3) = app.f(1:2:2048, 1:2:2448, 3);
            % 45 degrees
            app.pf(:,:,4) = app.f(1:2:2048, 2:2:2448, 4)

            app.CaptureStatusLamp.Color = ([0 1 0]);
        end

        % Button pushed function: RawImgButton
        function RawImgButtonPushed(app, event)
            % Raw images
            app.fg2=1;
            app.CaptureStatusLamp.Color = ([0 0 0]);

            imagesc(app.f(:,:,4),'Parent',app.UIAxes)
            colorbar(app.UIAxes)
            imagesc(app.f(:,:,2),'Parent',app.UIAxes_2)
            colorbar(app.UIAxes_2)
            imagesc(app.f(:,:,3),'Parent',app.UIAxes_3)
            colorbar(app.UIAxes_3)
            imagesc(app.f(:,:,1),'Parent',app.UIAxes_4)
            colorbar(app.UIAxes_4)
        end

        % Button pushed function: SplitPolzImgButton
        function SplitPolzImgButtonPushed(app, event)
            % Split images
            app.fg2=1;
            app.CaptureStatusLamp.Color = ([0 0 0]);

            imagesc(app.pf(:,:,4),'Parent',app.UIAxes)
            colorbar(app.UIAxes)
            imagesc(app.pf(:,:,2),'Parent',app.UIAxes_2)
            colorbar(app.UIAxes_2)
            imagesc(app.pf(:,:,3),'Parent',app.UIAxes_3)
            colorbar(app.UIAxes_3)
            imagesc(app.pf(:,:,1),'Parent',app.UIAxes_4)
            colorbar(app.UIAxes_4)
        end

        % Button pushed function: pStartButton
        function pStartButtonPushed(app, event)
            % Preview Camera for Image check
            app.fg2=1;
            pv = app.v;
            app.fg1 = 0;
            app.PreviewStatusLamp.Color = ([0 1 0]);
            if app.FrameRateEditField.Value == 0 && app.ExposureTimeEditField.Value==0
                app.FrameRateEditField.Value=5;
                app.ExposureTimeEditField.Value=100000;
            else
            end
            pv.AcquisitionFrameRate = app.FrameRateEditField.Value;
            pv.ExposureTime = app.ExposureTimeEditField.Value; %microseconds

            while app.fg1 == 0
                pvf=snapshot(pv);
                imagesc(pvf,'Parent',app.UIAxes_5)
                colorbar(app.UIAxes_5)
                pause(1)
            end

            app.PreviewStatusLamp.Color=([0 0 0])
            clear pv
        end

        % Button pushed function: pStopButton
        function pStopButtonPushed(app, event)
            % Stop Preview
            app.fg2 = 1;
            app.fg1 = 1;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save to set file name
            app.fg2 = 1;
            cd(fileparts(which(mfilename)));
            txn = sprintf('%s',app.FileNameEditField.Value);
            rawimgf = app.f;
            sptpolzimgf = app.pf;
            %             darkfilename = sprintf('dark%d_ms_%d',integrationTime3,d3);
            %             save(darkfilename,'darkspec','dspecmean','darkspecs');
            save(txn,'sptpolzimgf','rawimgf')
%             save(txn)
        end

        % Button pushed function: VButton_2
        function VButton_2Pushed(app, event)
            app.lv = 0.6;
            app.LEDVotageKnob.Value=app.lv*5;
            app.EditField2.Value=app.lv*5;
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            app.lv = 1;
            app.LEDVotageKnob.Value=app.lv*5;
            app.EditField2.Value=app.lv*5;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Channel 1 - 45')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Channel 2 - 0')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Channel 3 - 90')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Channel 4 - Circular')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ZTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Preview')
            app.UIAxes_5.ZTick = [];
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '1';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '2';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_3Label = uilabel(app.UIFigure);
            app.Lamp_3Label.Text = '3';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_4Label = uilabel(app.UIFigure);
            app.Lamp_4Label.Text = '4';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.LEDControlLabel = uilabel(app.UIFigure);
            app.LEDControlLabel.Text = 'LED Control';
            app.AllButton = uibutton(app.UIFigure, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.OffButton = uibutton(app.UIFigure, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            app.InitializeInstrumentsLabel = uilabel(app.UIFigure);
            app.InitializeInstrumentsLabel.Text = 'Initialize Instruments';
            app.ArudinoStartButton = uibutton(app.UIFigure, 'push');
            app.ArudinoStartButton.ButtonPushedFcn = createCallbackFcn(app, @ArudinoStartButtonPushed, true);
            app.ArudinoStartButton.Text = 'Arudino Start';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LucidStartButton_2 = uibutton(app.UIFigure, 'push');
            app.LucidStartButton_2.ButtonPushedFcn = createCallbackFcn(app, @LucidStartButton_2Pushed, true);
            app.LucidStartButton_2.Text = 'Lucid Start';
            app.CameraPropertiesLabel = uilabel(app.UIFigure);
            app.CameraPropertiesLabel.Text = 'Camera Properties';
            app.ExposureTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartCaptureButton = uibutton(app.UIFigure, 'push');
            app.StartCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @StartCaptureButtonPushed, true);
            app.StartCaptureButton.Text = 'Start Capture';
            app.FrameRateEditFieldLabel = uilabel(app.UIFigure);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEDVotageKnobLabel = uilabel(app.UIFigure);
            app.LEDVotageKnobLabel.Text = 'LED Votage';
            app.LEDVotageKnob = uiknob(app.UIFigure, 'continuous');
            app.LEDVotageKnob.Limits = [0 5];
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.RawImgButton = uibutton(app.UIFigure, 'push');
            app.RawImgButton.ButtonPushedFcn = createCallbackFcn(app, @RawImgButtonPushed, true);
            app.RawImgButton.Text = 'Raw Img';
            app.SplitPolzImgButton = uibutton(app.UIFigure, 'push');
            app.SplitPolzImgButton.ButtonPushedFcn = createCallbackFcn(app, @SplitPolzImgButtonPushed, true);
            app.SplitPolzImgButton.Text = 'Split Polz Img';
            app.CaptureStatusLampLabel = uilabel(app.UIFigure);
            app.CaptureStatusLampLabel.Text = 'Capture Status';
            app.CaptureStatusLamp = uilamp(app.UIFigure);
            app.PreviewLabel = uilabel(app.UIFigure);
            app.PreviewLabel.Text = 'Preview';
            app.pStartButton = uibutton(app.UIFigure, 'push');
            app.pStartButton.ButtonPushedFcn = createCallbackFcn(app, @pStartButtonPushed, true);
            app.pStartButton.Text = 'pStart';
            app.pStopButton = uibutton(app.UIFigure, 'push');
            app.pStopButton.ButtonPushedFcn = createCallbackFcn(app, @pStopButtonPushed, true);
            app.pStopButton.Text = 'pStop';
            app.SaveLabel = uilabel(app.UIFigure);
            app.SaveLabel.Text = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PreviewStatusLampLabel = uilabel(app.UIFigure);
            app.PreviewStatusLampLabel.Text = 'Preview Status';
            app.PreviewStatusLamp = uilamp(app.UIFigure);
            app.VoltsLabel = uilabel(app.UIFigure);
            app.VoltsLabel.Text = 'Volts';
            app.microsecondsLabel = uilabel(app.UIFigure);
            app.microsecondsLabel.Text = 'microseconds';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.VButton = uibutton(app.UIFigure, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = '5V';
            app.VButton_2 = uibutton(app.UIFigure, 'push');
            app.VButton_2.ButtonPushedFcn = createCallbackFcn(app, @VButton_2Pushed, true);
            app.VButton_2.Text = '3V';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeviceContrl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end